classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureGUI                matlab.ui.Figure
        LabelListBox             matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
        AlphaSliderLabel         matlab.ui.control.Label
        AlphaSlider              matlab.ui.control.Slider
        BetaSliderLabel          matlab.ui.control.Label
        BetaSlider               matlab.ui.control.Slider
        Alpha3SliderLabel        matlab.ui.control.Label
        Alpha3Slider             matlab.ui.control.Slider
        Alpha5SliderLabel        matlab.ui.control.Label
        Alpha5Slider             matlab.ui.control.Slider
        Alpha7SliderLabel        matlab.ui.control.Label
        Alpha7Slider             matlab.ui.control.Slider
        t1Label                  matlab.ui.control.Label
        t1                       matlab.ui.control.NumericEditField
        t2Label                  matlab.ui.control.Label
        t2                       matlab.ui.control.NumericEditField
        NumOfCyclesLabel         matlab.ui.control.Label
        NumOfCycles              matlab.ui.control.NumericEditField
        Frequency                matlab.ui.container.ButtonGroup
        f50                      matlab.ui.control.RadioButton
        f60                      matlab.ui.control.RadioButton
        TimeDiff                 matlab.ui.control.TextArea
        TauSliderLabel           matlab.ui.control.Label
        TauSlider                matlab.ui.control.Slider
        FnSliderLabel            matlab.ui.control.Label
        FnSlider                 matlab.ui.control.Slider
        KSliderLabel             matlab.ui.control.Label
        KSlider                  matlab.ui.control.Slider
        AlphaFlickerSliderLabel  matlab.ui.control.Label
        AlphaFlickerSlider       matlab.ui.control.Slider
        NoiseSliderLabel         matlab.ui.control.Label
        NoiseSlider              matlab.ui.control.Slider
        Noise                    matlab.ui.container.ButtonGroup
        NoNoise                  matlab.ui.control.RadioButton
        AWGN                     matlab.ui.control.RadioButton
        CreateDataBase           matlab.ui.control.Button
        TimesTable               matlab.ui.control.Button
        Cover1                   matlab.ui.control.TextArea
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % Time Parametes:
        t_start = 0;
        t_end ; % TODO : Chosen by User/Random
        dt = 0.0003125; %dt = 1./Fsample % TODO : ASK RAM Chosen by User/Random
        t;
        
        f = 50; % [Hz] 
        Tcycle;
        NumofTcycles = 10;
        
        % The current signal :
        signal;
        noisy_signal;
        signal_label = 'Normal';
        
        % Exporting signals :
        field1 = 'labels';
        field2 = 'signals';
        DataBase; %Defined in start-up callback
        
        DBDialogBoxExist = 0;
        
        % Guided tour in th app (for first use):
        GuideOrNot_ = 'No';
        InstructionsNum = 1;
        FirstUse = true;
        
    end
    
    properties (Access = private)
        DBDialogBox % Description
  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FigureGUI.Name ='Power Quality Signals GUI';
            
            %close ALL FORCE;% manage to close but also the wanted istance
            %close all;
            %clc;
            
            defaultOffVisibality(app);
            
            app.DataBase = struct(app.field1,[],app.field2,[]);% = {};
            
            app.Tcycle =1./(app.f); % [sec]
            app.t_end = app.NumofTcycles * app.Tcycle;
            app.t = [app.t_start : app.dt : app.t_end];
            
            % axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            
            %Deafultic signals, times:
            app.signal = sin(2*pi*app.f*app.t); 
            app.noisy_signal = awgn(app.signal,0); 
            app.t1.Value = 90; %[ms]
            app.t2.Value = 110; %[ms]
            
            % Users Instructions :
            app.GuideOrNot_ = GuideOrNot();
            if strcmp(app.GuideOrNot_,'No')
                app.Noise.Visible = 'on';
                app.NoiseSlider.Visible = 'on'; app.NoiseSliderLabel.Visible = 'on';
                app.Frequency.Visible = 'on';
                app.NumOfCycles.Visible = 'on'; app.NumOfCyclesLabel.Visible = 'on';
                app.CreateDataBase.Visible='on';
                app.UIAxes.Visible = 'on';
                PlotSignal(app);
            else
                GuiManual(app);
                PlotSignal(app);
            end
            
            app.Cover1.Visible = 'off';
            
        end

        % Value changed function: ListBox
        function DisListBox(app, event)
            app.signal_label = app.ListBox.Value;
            app.UIAxes.Title.String = app.signal_label;%Plotting
            
            % Guided tour msgs are being called when diff signals are chosen:
            if app.InstructionsNum>=2 && app.InstructionsNum<=5
                GuiManual(app);
            end
            
            %Sets parameter's range of each disturb :
            SetParametersRange( app ); 
            
            % Slideres visibality :
            if ~app.DBDialogBoxExist %DB app is not open
                SlidersVisibilty(app);
            else %DB app is open
                if strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                    SlidersVisibilty( app  );
                else
                    defaultOffVisibality(app);
                end
                app.DBDialogBox.CurSignalLabel.Value = app.signal_label;
            end
            
            % Create the basic signal :(Deafult parameters values):
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if ~t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                if  ~app.DBDialogBoxExist 
                    app.TimeDiff.Visible = 'on';
                    app.TimesTable.Visible = 'on';
                else
                    if strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                       app.TimeDiff.Visible = 'on';
                       app.TimesTable.Visible = 'on';
                    else
                        app.TimeDiff.Visible = 'off';
                        app.TimesTable.Visible = 'off';
                    end
                end
            else
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            end
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Value changed function: BetaSlider
        function BetaSliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Callback function
        function PlotSignal(app, event)
            if app.DBDialogBoxExist && ...
                    ~strcmp(app.DBDialogBox.TabGroup.SelectedTab.Title,'Current GUI`s signal')
                plot(app.UIAxes,1);
                return
            end
            if strcmp(app.Noise.SelectedObject.Text,'AWGN')
                plot(app.UIAxes,app.t*1E+3,app.noisy_signal); %miliseconds
                ylim(app.UIAxes,[-2 2]);%for low SNR: ylim(app.UIAxes,[-5 5]);
            else
                plot(app.UIAxes,app.t*1E+3,app.signal); %miliseconds
                %plot(app.UIAxes,app.t,app.signal); %seconds
                ylim(app.UIAxes,[-2 2]);
            end
            %axis([0 10*pi -1.5 1.5]);% TODO : let the user set boundaries?
            app.UIAxes.Title.String = app.signal_label;
            xlim(app.UIAxes,[0 200]);%milisec
            
            
            %TODO : zoom-in option ...
        end

        % Value changed function: Alpha3Slider
        function Alpha3SliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Value changed function: Alpha5Slider
        function Alpha5SliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Value changed function: Alpha7Slider
        function Alpha7SliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Selection changed function: Frequency
        function FreqSelectionChanged(app, event)
            switch app.Frequency.SelectedObject
                case app.f50
                    app.f = 50;
                case app.f60
                    app.f = 60;
            end
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
        end

        % Value changed function: NumOfCycles
        function NumOfCyclesValueChanged(app, event)
            
            app.NumofTcycles = app.NumOfCycles.Value;
            
            app.t_end = app.NumofTcycles*app.Tcycle;
            
            app.t = [app.t_start : app.dt : app.t_end ];
            app.t = app.t(1:end-1);
            [app.signal,app.noisy_signal]=specifyModel(app);
            
            PlotSignal(app);
            
        end

        % Value changed function: t1
        function t1ValueChanged(app, event)
            
            [app.signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
                %pop-up msg
            end
        end

        % Value changed function: t2
        function t2ValueChanged(app, event)

            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
                %pop-up msg
            end
        end

        % Value changed function: TauSlider
        function TauSliderValueChanged(app, event)
%             value = app.TauSlider.Value;
%             app.tau = value./1000 ;% ms to sec
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end
            
        end

        % Value changed function: FnSlider
        function FnSliderValueChanged(app, event)
%             app.Fn = app.FnSlider.Value;
            
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                PlotSignal(app);
            end        
        end

        % Value changed function: KSlider
        function KSliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end          
        end

        % Value changed function: AlphaFlickerSlider
        function AlphaFlickerSliderValueChanged(app, event)
            [app.signal,app.noisy_signal] = specifyModel(app);
            
            if t1_t2_diff_legal(app.t1.Value,app.t2.Value,app.Tcycle,app.signal_label)
                app.TimeDiff.Visible = 'off';
                app.TimesTable.Visible = 'off';
                PlotSignal(app);
            else
                app.TimeDiff.Visible = 'on';
                app.TimesTable.Visible = 'on';
            end           
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            SNRinDb = app.NoiseSlider.Value;
            
            app.noisy_signal = awgn(app.signal,SNRinDb);
            
            PlotSignal(app);
            
        end

        % Selection changed function: Noise
        function NoiseSelectionChanged(app, event)
            if strcmp(app.Noise.SelectedObject.Text,'AWGN')
                app.NoiseSlider.Enable = 'on';
                app.NoiseSliderLabel.Enable = 'on';
                PlotSignal(app);
            else
                app.NoiseSlider.Enable = 'off';
                app.NoiseSliderLabel.Enable = 'off';
                PlotSignal(app);
            end
        end

        % Button pushed function: CreateDataBase
        function CreateDBButtonPushed(app, event)
            % Disable GUI button options button while dialog is open
            app.DBDialogBoxExist =1;
            app.DBDialogBox = DataBaseCreator(); % Create the DB dialbox
            app.DBDialogBox.SetUpfunc(app); % Let DB dialbox access to mainAPP data
            % openvar('DataBaseCreator.DataBase');
            %export(app)
        end

        % Close request function: FigureGUI
        function FigureGUICloseRequest(app, event)
            close all force;
            delete(app);
        end

        % Button pushed function: TimesTable
        function TimesTableButtonPushed(app, event)
           imshow('TimesTable.JPG');
        end

        % Callback function
        function StartPlotting(app, event)
            %
            %             PlotOrNot = app.Switch.Value ;
            %             if strcmp(PlotOrNot,'On')
            %                 type = app.ListBox.Value;
            %                 [t,y,y_title] = choose_model_power_quality_signal_labelances(type);
            %             end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureGUI = uifigure('Visible', 'off');
            app.FigureGUI.Name = 'UI Figure';
            app.FigureGUI.CloseRequestFcn = createCallbackFcn(app, @FigureGUICloseRequest, true);
            app.LabelListBox = uilabel(app.FigureGUI);
            app.LabelListBox.Text = 'Disturbs List';
            app.ListBox = uilistbox(app.FigureGUI);
            app.ListBox.Items = {'Normal', 'Sag', 'Swell', 'Interruption', 'Harmonics', 'Oscillatory transient', 'Sag+Harmonics', 'Swell+Harmonics', 'Flicker', 'Notch', 'Spike', 'Flicker+Harmonics', 'Interruption+Harmonics', 'Flicker+Sag', 'Flicker+Swell', 'Impulsive Transient'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @DisListBox, true);
            app.ListBox.Value = 'Normal';
            app.AlphaSliderLabel = uilabel(app.FigureGUI);
            app.AlphaSliderLabel.Text = 'α';
            app.AlphaSlider = uislider(app.FigureGUI);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.BetaSliderLabel = uilabel(app.FigureGUI);
            app.BetaSliderLabel.Text = 'β';
            app.BetaSlider = uislider(app.FigureGUI);
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @BetaSliderValueChanged, true);
            app.Alpha3SliderLabel = uilabel(app.FigureGUI);
            app.Alpha3SliderLabel.Text = 'α3';
            app.Alpha3Slider = uislider(app.FigureGUI);
            app.Alpha3Slider.Limits = [0.05 0.15];
            app.Alpha3Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha3SliderValueChanged, true);
            app.Alpha3Slider.Value = 0.05;
            app.Alpha5SliderLabel = uilabel(app.FigureGUI);
            app.Alpha5SliderLabel.Text = 'α5';
            app.Alpha5Slider = uislider(app.FigureGUI);
            app.Alpha5Slider.Limits = [0.05 0.15];
            app.Alpha5Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha5SliderValueChanged, true);
            app.Alpha5Slider.Value = 0.05;
            app.Alpha7SliderLabel = uilabel(app.FigureGUI);
            app.Alpha7SliderLabel.Text = 'α7';
            app.Alpha7Slider = uislider(app.FigureGUI);
            app.Alpha7Slider.Limits = [0.05 0.15];
            app.Alpha7Slider.ValueChangedFcn = createCallbackFcn(app, @Alpha7SliderValueChanged, true);
            app.Alpha7Slider.Value = 0.05;
            app.t1Label = uilabel(app.FigureGUI);
            app.t1Label.Text = 't1';
            app.t1 = uieditfield(app.FigureGUI, 'numeric');
            app.t1.Limits = [0 1000];
            app.t1.ValueChangedFcn = createCallbackFcn(app, @t1ValueChanged, true);
            app.t2Label = uilabel(app.FigureGUI);
            app.t2Label.Text = 't2';
            app.t2 = uieditfield(app.FigureGUI, 'numeric');
            app.t2.Limits = [0 1000];
            app.t2.ValueChangedFcn = createCallbackFcn(app, @t2ValueChanged, true);
            app.NumOfCyclesLabel = uilabel(app.FigureGUI);
            app.NumOfCyclesLabel.Text = {'Number'; ' of'; ' Cycles'};
            app.NumOfCycles = uieditfield(app.FigureGUI, 'numeric');
            app.NumOfCycles.Limits = [1 50];
            app.NumOfCycles.ValueChangedFcn = createCallbackFcn(app, @NumOfCyclesValueChanged, true);
            app.NumOfCycles.Value = 10;
            app.Frequency = uibuttongroup(app.FigureGUI);
            app.Frequency.SelectionChangedFcn = createCallbackFcn(app, @FreqSelectionChanged, true);
            app.Frequency.Title = 'Frequency';
            app.f50 = uiradiobutton(app.Frequency);
            app.f50.Text = '50 Hz';
            app.f50.Value = true;
            app.f60 = uiradiobutton(app.Frequency);
            app.f60.Text = '60 Hz';
            app.TimeDiff = uitextarea(app.FigureGUI);
            app.TimeDiff.Value = {'The chosen time difference is not legal '};
            app.TauSliderLabel = uilabel(app.FigureGUI);
            app.TauSliderLabel.Text = 'τ';
            app.TauSlider = uislider(app.FigureGUI);
            app.TauSlider.Limits = [8 40];
            app.TauSlider.ValueChangedFcn = createCallbackFcn(app, @TauSliderValueChanged, true);
            app.TauSlider.Value = 8;
            app.FnSliderLabel = uilabel(app.FigureGUI);
            app.FnSliderLabel.Text = 'Fn';
            app.FnSlider = uislider(app.FigureGUI);
            app.FnSlider.Limits = [300 900];
            app.FnSlider.ValueChangedFcn = createCallbackFcn(app, @FnSliderValueChanged, true);
            app.FnSlider.Value = 300;
            app.KSliderLabel = uilabel(app.FigureGUI);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.FigureGUI);
            app.KSlider.Limits = [0.1 0.4];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged, true);
            app.KSlider.Value = 0.4;
            app.AlphaFlickerSliderLabel = uilabel(app.FigureGUI);
            app.AlphaFlickerSliderLabel.Text = {'αf'; ''};
            app.AlphaFlickerSlider = uislider(app.FigureGUI);
            app.AlphaFlickerSlider.Limits = [0.1 0.2];
            app.AlphaFlickerSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaFlickerSliderValueChanged, true);
            app.AlphaFlickerSlider.Value = 0.1;
            app.NoiseSliderLabel = uilabel(app.FigureGUI);
            app.NoiseSliderLabel.Enable = 'off';
            app.NoiseSliderLabel.Text = 'SNR';
            app.NoiseSlider = uislider(app.FigureGUI);
            app.NoiseSlider.Limits = [20 50];
            app.NoiseSlider.MajorTickLabels = {'20', '25', '30', '35', '40', '45', '50db'};
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.NoiseSlider.MinorTicks = [];
            app.NoiseSlider.Enable = 'off';
            app.NoiseSlider.Value = 50;
            app.Noise = uibuttongroup(app.FigureGUI);
            app.Noise.SelectionChangedFcn = createCallbackFcn(app, @NoiseSelectionChanged, true);
            app.Noise.Title = 'Noise';
            app.NoNoise = uiradiobutton(app.Noise);
            app.NoNoise.Text = 'No noise';
            app.NoNoise.Value = true;
            app.AWGN = uiradiobutton(app.Noise);
            app.AWGN.Text = 'AWGN';
            app.CreateDataBase = uibutton(app.FigureGUI, 'push');
            app.CreateDataBase.ButtonPushedFcn = createCallbackFcn(app, @CreateDBButtonPushed, true);
            app.CreateDataBase.Text = {'CREATE'; ' DATABASE'};
            app.TimesTable = uibutton(app.FigureGUI, 'push');
            app.TimesTable.ButtonPushedFcn = createCallbackFcn(app, @TimesTableButtonPushed, true);
            app.TimesTable.Text = 'Times Table Reminder';
            app.Cover1 = uitextarea(app.FigureGUI);
            app.UIAxes = uiaxes(app.FigureGUI);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time [ms]')
            ylabel(app.UIAxes, 'V [pu]')
            % Show the figure after all components are created
            app.FigureGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureGUI)
        end
    end
end